classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        MainGridLayout             matlab.ui.container.GridLayout
        TabGroup                   matlab.ui.container.TabGroup
        Tab1                       matlab.ui.container.Tab
        GridLayout                 matlab.ui.container.GridLayout
        App1ControlPanel           matlab.ui.container.Panel
        FillLocation               matlab.ui.control.TextArea
        DiskRadiusEditField        matlab.ui.control.NumericEditField
        DiskRadiusEditFieldLabel   matlab.ui.control.Label
        App1FillLocationLabel_2    matlab.ui.control.Label
        LineWidthEditField         matlab.ui.control.NumericEditField
        LineWidthEditFieldLabel    matlab.ui.control.Label
        CSobelEditField            matlab.ui.control.NumericEditField
        CSobelEditFieldLabel       matlab.ui.control.Label
        STDLoGEditField            matlab.ui.control.NumericEditField
        STDLoGEditFieldLabel       matlab.ui.control.Label
        SizeLoGEditField           matlab.ui.control.NumericEditField
        SizeLoGEditFieldLabel      matlab.ui.control.Label
        App1FillLocationLabel      matlab.ui.control.Label
        App1EdgeDetectionDropDown  matlab.ui.control.DropDown
        EdgeDetectionLabel         matlab.ui.control.Label
        App1LoadPanel              matlab.ui.container.Panel
        App1SaveFileButton         matlab.ui.control.Button
        App1FileName               matlab.ui.control.Label
        App1LoadFileButton         matlab.ui.control.Button
        App1InputLabel             matlab.ui.control.Label
        App1InputImage             matlab.ui.control.Image
        App1EdgeLabel              matlab.ui.control.Label
        App1EdgeImage              matlab.ui.control.Image
        App1MaskLabel              matlab.ui.control.Label
        App1MaskImage              matlab.ui.control.Image
        App1OutputLabel            matlab.ui.control.Label
        App1OutputImage            matlab.ui.control.Image
    end

    
    properties (Access = private)
        I = truecolorload('images\mrorange.jpg');
        filename = "No File Selected"

        O = truecolorload('images\1mansion.png');

        edgeDetection = "Laplacian";
    end

    methods (Static)
    
        function result = rgbtogray(I)
            result = rgb2gray(I);
            result(:,:,2) = result(:,:,1);
            result(:,:,3) = result(:,:,1);
        end

    end
    
    methods (Access = private)
        
        function loadImage(app)
        end

        function saveImage(~, im)
            % Get file name
            [file, path] = uiputfile({ ...
                '*.bmp', 'BMP files (*.bmp)'; ...
                '*.gif', 'GIF files (*.gif)'; ...
                '*.jpg', 'JPEG files (*jpg)'; ...
                '*.png', 'PNG files (*.png)'; ...
                '*.tif', 'TIFF files (*tif)' ...
            });
            if file == 0
                return
            end
            filepath = fullfile(path, file);
            disp(filepath);

            imwrite(im, filepath);
        end

        function updateApp1Values(app)
            app.App1InputImage.ImageSource = app.I;

            % Get variables
            values = app.FillLocation.Value;
            fill_loc = str2num(sprintf("%s; ", string(values)));

            line_width = app.LineWidthEditField.Value;
            disk_radius = app.DiskRadiusEditField.Value;

            log_size = app.SizeLoGEditField.Value;
            log_std = app.STDLoGEditField.Value;
            sobel_c = app.CSobelEditField.Value;

            % Get kernel based on pass filter
            gray = rgb2gray(app.I);
            e = log_edge(gray, log_size, log_std);
            e = imbinarize(e, 'adaptive');

            mask = imdilate(e, strel('line', line_width, 90));
            mask = imdilate(mask, strel('line', line_width, 0));
            mask = imdilate(mask, strel('disk', disk_radius));
            mask = imfill(mask, [320 240], 8);

            app.O = app.I .* uint8(mask);
            
            % Outputs
            app.App1EdgeImage.ImageSource = double(cat(3, e, e, e));
            app.App1MaskImage.ImageSource = double(cat(3, mask, mask, mask));
            app.App1OutputImage.ImageSource = app.O;
            app.App1FileName.Text = app.filename;
        end

        
        function updateBasedOnTab(app)
            selectedTab = app.TabGroup.SelectedTab;
            
            if selectedTab == app.Tab1
                app.updateApp1Values();
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.updateBasedOnTab();
        end

        % Button pushed function: App1LoadFileButton
        function App1LoadFileButtonPushed(app, event)
            % Load to I
            [file, path] = uigetfile('*');
            if file == 0
                return
            end
            filepath = fullfile(path, file);
            disp(filepath);
            app.filename = filepath;
            app.I = truecolorload(filepath);

            app.updateApp1Values();
        end

        % Button pushed function: App1SaveFileButton
        function App1SaveFileButtonPushed(app, event)
            app.saveImage(app.O);
        end

        % Value changed function: App1EdgeDetectionDropDown
        function App1EdgeDetectionDropDownValueChanged(app, event)
            value = app.App1EdgeDetectionDropDown.Value;
            
            app.edgeDetection = value;

            app.updateApp1Values();
        end

        % Callback function
        function App1Update(app, event)
            app.updateApp1Values();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainGridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.MainGridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = 'mr orange';
            app.GridLayout = uigridlayout(app.Tab1);
            app.App1OutputImage = uiimage(app.GridLayout);
            app.App1OutputImage.ImageSource = 'mrorange.jpg';
            app.App1OutputLabel = uilabel(app.GridLayout);
            app.App1OutputLabel.Text = 'Output Image';
            app.App1MaskImage = uiimage(app.GridLayout);
            app.App1MaskImage.ImageSource = 'mrorange.jpg';
            app.App1MaskLabel = uilabel(app.GridLayout);
            app.App1MaskLabel.Text = 'Segmentation Mask';
            app.App1EdgeImage = uiimage(app.GridLayout);
            app.App1EdgeImage.ImageSource = 'mrorange.jpg';
            app.App1EdgeLabel = uilabel(app.GridLayout);
            app.App1EdgeLabel.Text = 'Edge Image';
            app.App1InputImage = uiimage(app.GridLayout);
            app.App1InputImage.ImageSource = 'mrorange.jpg';
            app.App1InputLabel = uilabel(app.GridLayout);
            app.App1InputLabel.Text = 'Input Image';
            app.App1ControlPanel = uipanel(app.GridLayout);
            app.App1LoadPanel = uipanel(app.App1ControlPanel);
            app.App1LoadFileButton = uibutton(app.App1LoadPanel, 'push');
            app.App1LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @App1LoadFileButtonPushed, true);
            app.App1LoadFileButton.Text = 'Load File';
            app.App1FileName = uilabel(app.App1LoadPanel);
            app.App1FileName.Text = 'No File Selected';
            app.App1SaveFileButton = uibutton(app.App1LoadPanel, 'push');
            app.App1SaveFileButton.ButtonPushedFcn = createCallbackFcn(app, @App1SaveFileButtonPushed, true);
            app.App1SaveFileButton.Text = 'Save File';
            app.EdgeDetectionLabel = uilabel(app.App1ControlPanel);
            app.EdgeDetectionLabel.Text = 'Edge Detection';
            app.App1EdgeDetectionDropDown = uidropdown(app.App1ControlPanel);
            app.App1EdgeDetectionDropDown.Items = {'Laplacian', 'LoG', 'Sobel', 'Prewitt', 'Roberts', 'Canny'};
            app.App1EdgeDetectionDropDown.ValueChangedFcn = createCallbackFcn(app, @App1EdgeDetectionDropDownValueChanged, true);
            app.App1EdgeDetectionDropDown.Value = 'Laplacian';
            app.App1FillLocationLabel = uilabel(app.App1ControlPanel);
            app.App1FillLocationLabel.Text = 'Fill Location (format: x, y)';
            app.SizeLoGEditFieldLabel = uilabel(app.App1ControlPanel);
            app.SizeLoGEditFieldLabel.Text = 'Size (LoG)';
            app.SizeLoGEditField = uieditfield(app.App1ControlPanel, 'numeric');
            app.SizeLoGEditField.Limits = [5 Inf];
            app.SizeLoGEditField.RoundFractionalValues = 'on';
            app.SizeLoGEditField.Value = 25;
            app.STDLoGEditFieldLabel = uilabel(app.App1ControlPanel);
            app.STDLoGEditFieldLabel.Text = 'STD (LoG)';
            app.STDLoGEditField = uieditfield(app.App1ControlPanel, 'numeric');
            app.STDLoGEditField.Limits = [0 Inf];
            app.STDLoGEditField.Value = 1.5;
            app.CSobelEditFieldLabel = uilabel(app.App1ControlPanel);
            app.CSobelEditFieldLabel.Text = 'C (Sobel)';
            app.CSobelEditField = uieditfield(app.App1ControlPanel, 'numeric');
            app.CSobelEditField.Limits = [1 Inf];
            app.CSobelEditField.Value = 2;
            app.LineWidthEditFieldLabel = uilabel(app.App1ControlPanel);
            app.LineWidthEditFieldLabel.Text = 'Line Width';
            app.LineWidthEditField = uieditfield(app.App1ControlPanel, 'numeric');
            app.LineWidthEditField.Value = 10;
            app.App1FillLocationLabel_2 = uilabel(app.App1ControlPanel);
            app.App1FillLocationLabel_2.Text = 'Dilation Structural Element Parameters';
            app.DiskRadiusEditFieldLabel = uilabel(app.App1ControlPanel);
            app.DiskRadiusEditFieldLabel.Text = 'Disk Radius';
            app.DiskRadiusEditField = uieditfield(app.App1ControlPanel, 'numeric');
            app.DiskRadiusEditField.Value = 10;
            app.FillLocation = uitextarea(app.App1ControlPanel);
            app.FillLocation.Value = {'320 240'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end